classdef livemRNAApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LivemRNAAnalysisUIFigure        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SelectprojectMenu               matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ExportDataTab                   matlab.ui.container.Tab
        RundataexportButton             matlab.ui.control.Button
        NuclearchannelprojectiontypeLabel  matlab.ui.control.Label
        NuclearchannelprojectiontypeDropDown  matlab.ui.control.DropDown
        FilteringTab                    matlab.ui.container.Tab
        highprecisionCheckBox           matlab.ui.control.CheckBox
        keeppoolCheckBox                matlab.ui.control.CheckBox
        displayfiguresCheckBox          matlab.ui.control.CheckBox
        numberofframesEditFieldLabel    matlab.ui.control.Label
        numberofframesEditField         matlab.ui.control.NumericEditField
        runFilterMovie                  matlab.ui.control.Button
        FilterDropDownLabel             matlab.ui.control.Label
        FilterDropDown                  matlab.ui.control.DropDown
        numberofworkersEditFieldLabel   matlab.ui.control.Label
        numberofworkersEditField        matlab.ui.control.NumericEditField
        usesthedefaultvaluesforfilteringLabel  matlab.ui.control.Label
        filterAxes                      matlab.ui.control.UIAxes
        ShowpreviewCheckBox             matlab.ui.control.CheckBox
        FrameSliderLabel                matlab.ui.control.Label
        FrameSlider                     matlab.ui.control.Slider
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        zSliderLabel                    matlab.ui.control.Label
        zSlider                         matlab.ui.control.Slider
        Sigma1SpinnerLabel              matlab.ui.control.Label
        firstsigma                      matlab.ui.control.Spinner
        Sigma2SpinnerLabel              matlab.ui.control.Label
        secondsigma                     matlab.ui.control.Spinner
        kernelsizeSpinnerLabel          matlab.ui.control.Label
        kernelsizeSpinner               matlab.ui.control.Spinner
        forDoGmakesuresigma2sigma1Label  matlab.ui.control.Label
        SegmentationTab                 matlab.ui.container.Tab
        keeppoolCheckBox_2              matlab.ui.control.CheckBox
        displayfiguresCheckBox_2        matlab.ui.control.CheckBox
        runSegmentSpots                 matlab.ui.control.Button
        numberofworkersEditField_2Label  matlab.ui.control.Label
        numberofworkersEditField_2      matlab.ui.control.NumericEditField
        thresholdGUICheckBox            matlab.ui.control.CheckBox
        FrameEditFieldLabel             matlab.ui.control.Label
        firstFrameEditField             matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        LastFrameEditField              matlab.ui.control.NumericEditField
        numberofshadowsDropDownLabel    matlab.ui.control.Label
        numberofshadowsDropDown         matlab.ui.control.DropDown
        nointegralzCheckBox             matlab.ui.control.CheckBox
        integrationscaleEditFieldLabel  matlab.ui.control.Label
        integrationscaleEditField       matlab.ui.control.NumericEditField
        Thresholdch1EditFieldLabel      matlab.ui.control.Label
        Thresholdch1EditField           matlab.ui.control.NumericEditField
        Thresholdch2EditFieldLabel      matlab.ui.control.Label
        Thresholdch2EditField           matlab.ui.control.NumericEditField
        ifapplicableLabel               matlab.ui.control.Label
        segAxes                         matlab.ui.control.UIAxes
        TrackingTab                     matlab.ui.container.Tab
        runTrackmRNADynamics            matlab.ui.control.Button
        runCheckParticleTracking        matlab.ui.control.Button
        integrationscaleEditField_2Label  matlab.ui.control.Label
        integrationscaleEditField_2     matlab.ui.control.NumericEditField
        trackAxes2                      matlab.ui.control.UIAxes
        trackAxes1                      matlab.ui.control.UIAxes
        AxisregistrationTab             matlab.ui.container.Tab
        runFindAPAxis                   matlab.ui.control.Button
        runAddParticlePosition          matlab.ui.control.Button
        CorrectAxisCheckBox             matlab.ui.control.CheckBox
        ManualAlignmentCheckBox         matlab.ui.control.CheckBox
        runCheckDivisionTimes           matlab.ui.control.Button
        fullEmbryoAxis                  matlab.ui.control.UIAxes
        PostanalysisTab                 matlab.ui.container.Tab
        runCompileParticles             matlab.ui.control.Button
        skipallCheckBox                 matlab.ui.control.CheckBox
        approveallCheckBox              matlab.ui.control.CheckBox
        minimumparticlesperAPbinEditFieldLabel  matlab.ui.control.Label
        minimumparticlesperAPbinEditField  matlab.ui.control.NumericEditField
        minimumframesinaparticletraceEditFieldLabel  matlab.ui.control.Label
        minimumframesinaparticletraceEditField  matlab.ui.control.NumericEditField
        integrationareaDropDownLabel    matlab.ui.control.Label
        integrationareaDropDown         matlab.ui.control.DropDown
        ROICheckBox                     matlab.ui.control.CheckBox
        ROI1EditFieldLabel              matlab.ui.control.Label
        ROI1EditField                   matlab.ui.control.NumericEditField
        ROI2EditFieldLabel              matlab.ui.control.Label
        ROI2EditField                   matlab.ui.control.NumericEditField
        nohistonechannelCheckBox        matlab.ui.control.CheckBox
        NuclearsegmentationandtrackingTab  matlab.ui.container.Tab
        runTrackNuclei                  matlab.ui.control.Button
        Tr2DCheckBox                    matlab.ui.control.CheckBox
        runCheckNucleiSegmentation      matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        PrefixEditField_4Label          matlab.ui.control.Label
        PrefixEditField                 matlab.ui.control.EditField
        MovieexportedLampLabel          matlab.ui.control.Label
        MovieexportedLamp               matlab.ui.control.Lamp
        MoviefilteredLampLabel          matlab.ui.control.Label
        MoviefilteredLamp               matlab.ui.control.Lamp
        ParticlessegmentedLampLabel     matlab.ui.control.Label
        ParticlessegmentedLamp          matlab.ui.control.Lamp
        ParticlestrackedLampLabel       matlab.ui.control.Label
        ParticlestrackedLamp            matlab.ui.control.Lamp
        CheckFrameInfoButton            matlab.ui.control.Button
        ExperimenttypeTextAreaLabel     matlab.ui.control.Label
        Experimenttype                  matlab.ui.control.TextArea
        RefreshButton                   matlab.ui.control.Button
        ChecklogfileButton              matlab.ui.control.Button
        Channel1Label                   matlab.ui.control.Label
        ch1Label                        matlab.ui.control.TextArea
        Channel3Label                   matlab.ui.control.Label
        ch3Label                        matlab.ui.control.TextArea
        Channel2Label                   matlab.ui.control.Label
        ch2Label                        matlab.ui.control.TextArea
        APaxisregisteredLampLabel       matlab.ui.control.Label
        APaxisregisteredLamp            matlab.ui.control.Lamp
        ParticlesregisteredtoaxisLampLabel  matlab.ui.control.Label
        ParticlesregisteredtoaxisLamp   matlab.ui.control.Lamp
        NucleitrackedLampLabel          matlab.ui.control.Label
        NucleitrackedLamp               matlab.ui.control.Lamp
        MitosescheckedLampLabel         matlab.ui.control.Label
        MitosescheckedLamp              matlab.ui.control.Lamp
        ParticlescompiledLabel          matlab.ui.control.Label
        ParticlescompiledLamp           matlab.ui.control.Lamp
        PopupPanel                      matlab.ui.container.Panel
    end

    
    properties (Access = private)
        prefix = ''; % this is the project to be analyzed      
    end
    
    methods (Access = private)
        
        function setPipelineStatus(app, prefix_input)
            
            app.prefix = prefix_input;
            
            [SourcePath,FISHPath,DropboxFolder,MS2CodePath, PreProcPath,...
                Folder, Prefix, ExperimentType,Channel1,Channel2,OutputFolder, Channel3] = readMovieDatabase(app.prefix);
                
                if exist([DropboxFolder,filesep,Prefix, filesep,'Particles.mat'], 'file')
                    load([DropboxFolder,filesep,Prefix, filesep,'Particles.mat'])
                    if ~iscell(Particles)
                        Particles = {Particles};
                    end
                    for Ch = 1:length(Particles)
                        if isfield(Particles{Ch},'APpos') 
                            app.ParticlesregisteredtoaxisLamp.Color = [.5 1 .5];
                        end
                    end
                    app.ParticlestrackedLamp.Color = [.5 1 .5];
                end
                
                if exist([DropboxFolder,filesep,Prefix, filesep,'Spots.mat'], 'file')
                    app.ParticlessegmentedLamp.Color = [.5 1 .5];
                end
                
                if exist([DropboxFolder,filesep,Prefix, filesep,'FrameInfo.mat'], 'file')
                    app.MovieexportedLamp.Color = [.5 1 .5];
                end
                
                if exist([FISHPath,filesep,Prefix,'_'], 'dir')
                    app.MoviefilteredLamp.Color = [.5 1 .5];
                end
                
                if exist([DropboxFolder,filesep, Prefix, filesep,Prefix,'_lin.mat'], 'file') && exist([DropboxFolder,filesep,Prefix, filesep,'Ellipses.mat'], 'file')
                    app.NucleitrackedLamp.Color = [.5 1 .5];
                end
                
                if exist([DropboxFolder,filesep,Prefix, filesep, 'APDetection.mat'], 'file') 
                    app.APaxisregisteredLamp.Color = [.5 1 .5];
                end
                
                if exist([DropboxFolder,filesep,Prefix, filesep, 'APDivision.mat'], 'file') 
                    app.APaxisregisteredLamp.Color = [.5 1 .5];
                end
                
                if exist([DropboxFolder,filesep,Prefix, filesep, 'CompiledParticles.mat'], 'file') 
                    app.ParticlescompiledLamp.Color = [.5 1 .5];
                end
      
                app.PrefixEditField.Value = app.prefix;
                app.Experimenttype.Value = ExperimentType;
                app.ch1Label.Value = Channel1;
                app.ch2Label.Value = Channel2;
                app.ch3Label.Value = Channel3;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                
                prefix_input = varargin{1};
                               
                setPipelineStatus(app, prefix_input)
                
            end
            
            set(findall(app.LivemRNAAnalysisUIFigure,'-property','FontSize'),'FontSize',14)
            set(findall(app.LivemRNAAnalysisUIFigure,'-property', 'FontName'), 'FontName', 'Lucida Sans')
            imshow(zeros(512), 'Border','Tight','Parent', app.filterAxes)

        end

        % Button pushed function: runFilterMovie
        function runFilterMoviePushed(app, event)
            
            options = {};
            
            %optional settings
            if app.highprecisionCheckBox.Value
                options = [options, 'highPrecision'];
            end
            if app.keeppoolCheckBox.Value
                options = [options, 'keepPool'];
            end
            if app.displayfiguresCheckBox.Value
                options = [options, 'displayFigures'];
            end
            if app.numberofframesEditField.Value ~= Inf
                options = [options, 'numFrames',app.numberofframesEditField.Value]; 
            end
             if app.kernelsizeSpinner.Value ~= 0
                options = [options, 'kernelSize',app.kernelsizeSpinner.Value]; 
            end
            
            if app.firstsigma.Value
                if strcmpi(app.FilterDropDown.Value,'Difference_of_Gaussian') ||  strcmpi(app.FilterDropDown.Value,'Structure_largest') ||  strcmpi(app.FilterDropDown.Value,'Structure_smallest')
                    options = [options, 'customFilter', app.FilterDropDown.Value, {{app.firstsigma.Value, app.secondsigma.Value}}];
                else
                    %sigma's double-celled because matlab ignores the first cell for some reason
                    options = [options, 'customFilter', app.FilterDropDown.Value, {{app.firstsigma.Value}}];
                end
            end
                
            options = [options, 'nWorkers',app.numberofworkersEditField.Value];
                            
            filterMovie(app.prefix, options{:}, 'app', app.filterAxes)          

        end

        % Menu selected function: SelectprojectMenu
        function SelectprojectMenuSelected(app, event)
            
            [SourcePath,FISHPath,DropboxFolder,MS2CodePath, PreProcPath,...
            Folder, prefix_output, ExperimentType,Channel1,Channel2,OutputFolder, Channel3] = readMovieDatabase('');
            
            
            setPipelineStatus(app, prefix_output)


        end

        % Button pushed function: runSegmentSpots
        function runSegmentSpotsPushed(app, event)
            
            threshold = -1;
            options = {};
            
            
             [SourcePath,FISHPath,DropboxFolder,MS2CodePath, PreProcPath,...
                Folder, Prefix, ExperimentType,Channel1,Channel2,OutputFolder, Channel3] = readMovieDatabase(app.prefix);
            
             %optional settings
            if app.thresholdGUICheckBox.Value
                options = [options, 'autoThresh'];
            else
                if strcmpi(ExperimentType, '2spot2color')
                    threshold = [app.Thresholdch1EditField.Value, app.Thresholdch2EditField.Value];
                else 
                    threshold = app.Thresholdch1EditField.Value;
                end
            end      
            if app.keeppoolCheckBox_2.Value
                options = [options, 'keepPool'];
            end
            if app.displayfiguresCheckBox_2.Value
                options = [options, 'displayFigures'];
            end
            if app.LastFrameEditField.Value ~= Inf 
                options = [options, 'Frames',app.LastFrameEditField.Value]; 
            end
            if app.nointegralzCheckBox.Value
                options = [options, 'noIntegralZ'];
            end
                
            options = [options, 'nWorkers',app.numberofworkersEditField_2.Value];          
            options = [options, 'intScale', app.integrationscaleEditField.Value ];            
            options = [options, 'Shadows', str2double(app.numberofshadowsDropDown.Value)];
            options = [options, 'firstFrame',app.firstFrameEditField.Value]; 
            
            segmentSpots(app.prefix, threshold, options{:})  
            
        end

        % Button pushed function: runTrackmRNADynamics
        function runTrackmRNADynamicsButtonPushed(app, event)
                  
            threshold1 = 0;
            threshold2 = 0;
            
            if strcmpi(app.Experimenttype.Value, '2spot2color')
                threshold1 = [0, 0];
                threshold2 = [0, 0];
            end
            
            TrackmRNADynamics(app.prefix, threshold1, threshold2,'app', app.trackAxes1, app.trackAxes2)
            
        end

        % Button pushed function: RundataexportButton
        function RundataexportButtonPushed(app, event)
            
            options = {};
            
            options = [options, app.NuclearchannelprojectiontypeDropDown.Value];
            
            Prefix = ExportDataForFISH(options);
            
            setPipelineStatus(app, Prefix)
            
        end

        % Value changed function: ChannelDropDown, FilterDropDown, 
        % FrameSlider, ShowpreviewCheckBox, firstsigma, 
        % kernelsizeSpinner, secondsigma, zSlider
        function updatePreview(app, event)
            
            if app.ShowpreviewCheckBox.Value
           
                [SourcePath,FISHPath,DropboxFolder,MS2CodePath, PreProcPath,...
                Folder, Prefix, ExperimentType,Channel1,Channel2,OutputFolder, Channel3] = readMovieDatabase(app.prefix);
            
                load([DropboxFolder, filesep, Prefix, filesep, 'FrameInfo.mat']);
                zMax = FrameInfo(1).NumberSlices + 2;
                app.zSlider.Limits = [1, zMax];
                pixelSize = FrameInfo(1).PixelSize * 1000; %nm
                frameMax = length(FrameInfo);
                app.FrameSlider.Limits = [1, frameMax];
                                
                frame = round(app.FrameSlider.Value);
                z = round(app.zSlider.Value);
                channelIndex = str2double(app.ChannelDropDown.Value);
                
                if strcmpi(ExperimentType, 'inputoutput') || strcmpi(ExperimentType, 'lattice')
                    coatChannel = getCoatChannel(ExperimentType, Channel1, Channel2);
                    nameSuffix= ['_ch', iIndex(coatChannel, 2)];
                else
                    nameSuffix = ['_ch', iIndex(channelIndex, 2)];
                end
                
                if (channelIndex == 1 && ~strcmpi(Channel1, 'none')) || (channelIndex == 2 && ~strcmpi(Channel2, 'none')) 
                    imPath = [PreProcPath, filesep, Prefix, filesep, Prefix, '_', iIndex(frame, 3), '_z',...
                    iIndex(z, 2), nameSuffix, '.tif'];
                
                    im = double(imread(imPath));
                    
                    if app.kernelsizeSpinner.Value == 0
                            filterSize = round(2000/pixelSize); %2000nm seems to be a good size empirically -AR
                    else
                        filterSize = app.kernelsizeSpinner.Value;
                    end
                    
                    filterType = app.FilterDropDown.Value;
                    
                    use2sigmas = 0;
                    
                     if strcmp(filterType, 'Difference_of_Gaussian') || ...
                        strcmp(filterType, 'Structure_largest') || ...
                        strcmp(filterType, 'Structure_smallest')
                    
                            use2sigmas = 1;
                            
                     end
                    
                    if use2sigmas
                        
                        s = {app.firstsigma.Value, app.secondsigma.Value};
                    
                        if ~s{1} || ~s{2}
                            
                           s = {1, round(42000/pixelSize)}; %good initial conditions determined empirically
                           
                        end
                        
                    else
                        
                        if app.firstsigma.Value
                            s = {app.firstsigma.Value};
                        else
                            s = {3}; %seems like a good default size to prevent errors?
                        end 
                        
                        if app.kernelsizeSpinner.Value == 0
                            filterSize = s{1}*3;
                        else
                            filterSize = app.kernelsizeSpinner.Value;
                        end
                        
                    end


                    fIm = filterImage(im, filterType, s, filterSize);
                    
                    
                    imshow(fIm , [median(fIm(:)), max(fIm(:))],'Border','Tight', 'Parent', app.filterAxes, 'InitialMagnification', 'fit');    
                end
            else
                
                imshow(zeros(512), 'Border','Tight','Parent', app.filterAxes,'InitialMagnification', 'fit')
                
            end
            
        end

        % Callback function: PrefixEditField, RefreshButton
        function prefixLoaded(app, event)
               
            
            setPipelineStatus(app, app.prefix)
                
                
        end

        % Button pushed function: runFindAPAxis
        function runFindAPAxisButtonPushed(app, event)
            
            
            if app.CorrectAxisCheckBox.Value 
                FindAPAxisFullEmbryo(app.prefix, 'CorrectAxis');
            else
                FindAPAxisFullEmbryo(app.prefix);
            end
            
        end

        % Button pushed function: runAddParticlePosition
        function runAddParticlePositionButtonPushed(app, event)
            
            if app.ManualAlignmentCheckBox.Value
                AddParticlePosition(app.prefix, 'ManualAlignment');
            else
                AddParticlePosition(app.prefix);
            end
           
        end

        % Button pushed function: runCheckParticleTracking
        function runCheckParticleTrackingButtonPushed(app, event)
            
            if app.integrationscaleEditField_2.Value ~= 1
                CheckParticleTracking(app.prefix, 'intScale', app.integrationscaleEditField_2.Value)
            else
                CheckParticleTracking(app.prefix)
            end
            
        end

        % Button pushed function: runTrackNuclei
        function runTrackNucleiButtonPushed(app, event)
            
            if app.Tr2DCheckBox.Value
                StartTr2D(app.prefix);
            else
                TrackNuclei(app.prefix);
            end
            
        end

        % Button pushed function: runCheckNucleiSegmentation
        function runCheckNucleiSegmentationButtonPushed(app, event)
            try
                CheckNucleiSegmentation(app.prefix);
            catch
                msgbox('make sure to track nuclei first')
            end
            
        end

        % Button pushed function: runCheckDivisionTimes
        function runCheckDivisionTimesButtonPushed(app, event)
            
            CheckDivisionTimes(app.prefix)
        end

        % Button pushed function: runCompileParticles
        function runCompileParticlesButtonPushed(app, event)
            
             options = {};
             
             if app.skipallCheckBox.Value
                options = [options, 'SkipAll'];
             end
             if app.approveallCheckBox.Value
                options = [options, 'ApproveAll'];
             end
             if app.nohistonechannelCheckBox.Value
                options = [options, 'noHist'];
             end
             if app.ROICheckBox.Value
                options = [options, 'ROI', app.ROI1EditField.Value, app.ROI2EditField.Value];
             end
             
             options = [options, 'MinTime', app.minimumframesinaparticletraceEditField.Value];
             options = [options, 'intArea', str2double(app.integrationareaDropDown.Value)];
             options = [options, 'MinParticles', app.minimumparticlesperAPbinEditField.Value];
             
             CompileParticles(app.prefix, options{:})
             
             
        end

        % Value changed function: numberofshadowsDropDown
        function numberofshadowsDropDownValueChanged(app, event)
            value = app.numberofshadowsDropDown.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LivemRNAAnalysisUIFigure = uifigure;
            app.LivemRNAAnalysisUIFigure.Name = 'Live mRNA Analysis';
            app.FileMenu = uimenu(app.LivemRNAAnalysisUIFigure);
            app.FileMenu.Text = 'File';
            app.SelectprojectMenu = uimenu(app.FileMenu);
            app.SelectprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectprojectMenuSelected, true);
            app.SelectprojectMenu.Text = 'Select project...';
            app.HelpMenu = uimenu(app.LivemRNAAnalysisUIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.LivemRNAAnalysisUIFigure);
            app.ExportDataTab = uitab(app.TabGroup);
            app.ExportDataTab.Title = 'Export Data';
            app.RundataexportButton = uibutton(app.ExportDataTab, 'push');
            app.RundataexportButton.ButtonPushedFcn = createCallbackFcn(app, @RundataexportButtonPushed, true);
            app.RundataexportButton.Text = 'Run data export ';
            app.NuclearchannelprojectiontypeLabel = uilabel(app.ExportDataTab);
            app.NuclearchannelprojectiontypeLabel.Tooltip = {'Nuclear channel projection type'};
            app.NuclearchannelprojectiontypeLabel.Text = 'Nuclear channel projection type';
            app.NuclearchannelprojectiontypeDropDown = uidropdown(app.ExportDataTab);
            app.NuclearchannelprojectiontypeDropDown.Items = {'maxprojection', 'medianprojection', 'middleprojection'};
            app.NuclearchannelprojectiontypeDropDown.Tooltip = {'maxprojection - the standard option'};
            app.NuclearchannelprojectiontypeDropDown.Value = 'maxprojection';
            app.FilteringTab = uitab(app.TabGroup);
            app.FilteringTab.Title = 'Filtering';
            app.highprecisionCheckBox = uicheckbox(app.FilteringTab);
            app.highprecisionCheckBox.Text = 'high precision';
            app.keeppoolCheckBox = uicheckbox(app.FilteringTab);
            app.keeppoolCheckBox.Text = 'keep pool';
            app.displayfiguresCheckBox = uicheckbox(app.FilteringTab);
            app.displayfiguresCheckBox.Text = 'display figures';
            app.displayfiguresCheckBox.Value = true;
            app.numberofframesEditFieldLabel = uilabel(app.FilteringTab);
            app.numberofframesEditFieldLabel.Text = 'number of frames';
            app.numberofframesEditField = uieditfield(app.FilteringTab, 'numeric');
            app.numberofframesEditField.Limits = [1 Inf];
            app.numberofframesEditField.RoundFractionalValues = 'on';
            app.numberofframesEditField.Value = Inf;
            app.runFilterMovie = uibutton(app.FilteringTab, 'push');
            app.runFilterMovie.ButtonPushedFcn = createCallbackFcn(app, @runFilterMoviePushed, true);
            app.runFilterMovie.Text = 'Run filtering';
            app.FilterDropDownLabel = uilabel(app.FilteringTab);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.FilteringTab);
            app.FilterDropDown.Items = {'Difference_of_Gaussian', 'Identity', 'Maximum', 'Laplacian', 'Mean', 'Hessian_largest', 'Hessian_smallest', 'Minimum', 'Median', 'Gaussian_blur', 'bright_spot_psf'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.FilterDropDown.Value = 'Difference_of_Gaussian';
            app.numberofworkersEditFieldLabel = uilabel(app.FilteringTab);
            app.numberofworkersEditFieldLabel.Text = 'number of workers';
            app.numberofworkersEditField = uieditfield(app.FilteringTab, 'numeric');
            app.numberofworkersEditField.Value = 8;
            app.usesthedefaultvaluesforfilteringLabel = uilabel(app.FilteringTab);
            app.usesthedefaultvaluesforfilteringLabel.Text = '0 uses the default values for filtering';
            app.filterAxes = uiaxes(app.FilteringTab);
            app.filterAxes.XTick = [];
            app.filterAxes.YTick = [];
            app.filterAxes.ZTick = [];
            app.ShowpreviewCheckBox = uicheckbox(app.FilteringTab);
            app.ShowpreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.ShowpreviewCheckBox.Text = 'Show preview';
            app.FrameSliderLabel = uilabel(app.FilteringTab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.FilteringTab);
            app.FrameSlider.Limits = [1 100];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.FrameSlider.MinorTicks = [];
            app.FrameSlider.Value = 2;
            app.ChannelDropDownLabel = uilabel(app.FilteringTab);
            app.ChannelDropDownLabel.Text = 'Channel ';
            app.ChannelDropDown = uidropdown(app.FilteringTab);
            app.ChannelDropDown.Items = {'1', '2'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.ChannelDropDown.Value = '1';
            app.zSliderLabel = uilabel(app.FilteringTab);
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.FilteringTab);
            app.zSlider.Limits = [1 30];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.zSlider.MinorTicks = [];
            app.zSlider.Value = 10;
            app.Sigma1SpinnerLabel = uilabel(app.FilteringTab);
            app.Sigma1SpinnerLabel.Text = 'Sigma 1';
            app.firstsigma = uispinner(app.FilteringTab);
            app.firstsigma.Step = 0.25;
            app.firstsigma.Limits = [0 Inf];
            app.firstsigma.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.Sigma2SpinnerLabel = uilabel(app.FilteringTab);
            app.Sigma2SpinnerLabel.Text = 'Sigma 2';
            app.secondsigma = uispinner(app.FilteringTab);
            app.secondsigma.Step = 0.25;
            app.secondsigma.Limits = [0 Inf];
            app.secondsigma.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.kernelsizeSpinnerLabel = uilabel(app.FilteringTab);
            app.kernelsizeSpinnerLabel.Text = 'kernel size';
            app.kernelsizeSpinner = uispinner(app.FilteringTab);
            app.kernelsizeSpinner.Limits = [0 Inf];
            app.kernelsizeSpinner.RoundFractionalValues = 'on';
            app.kernelsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.forDoGmakesuresigma2sigma1Label = uilabel(app.FilteringTab);
            app.forDoGmakesuresigma2sigma1Label.Text = 'for DoG, make sure sigma 2 > sigma 1';
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.keeppoolCheckBox_2 = uicheckbox(app.SegmentationTab);
            app.keeppoolCheckBox_2.Text = 'keep pool';
            app.displayfiguresCheckBox_2 = uicheckbox(app.SegmentationTab);
            app.displayfiguresCheckBox_2.Text = 'display figures';
            app.runSegmentSpots = uibutton(app.SegmentationTab, 'push');
            app.runSegmentSpots.ButtonPushedFcn = createCallbackFcn(app, @runSegmentSpotsPushed, true);
            app.runSegmentSpots.Text = 'Run segmentation';
            app.numberofworkersEditField_2Label = uilabel(app.SegmentationTab);
            app.numberofworkersEditField_2Label.Text = 'number of workers';
            app.numberofworkersEditField_2 = uieditfield(app.SegmentationTab, 'numeric');
            app.numberofworkersEditField_2.Limits = [0 Inf];
            app.numberofworkersEditField_2.RoundFractionalValues = 'on';
            app.numberofworkersEditField_2.Value = 8;
            app.thresholdGUICheckBox = uicheckbox(app.SegmentationTab);
            app.thresholdGUICheckBox.Text = 'threshold GUI';
            app.thresholdGUICheckBox.Value = true;
            app.FrameEditFieldLabel = uilabel(app.SegmentationTab);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.firstFrameEditField = uieditfield(app.SegmentationTab, 'numeric');
            app.firstFrameEditField.UpperLimitInclusive = 'off';
            app.firstFrameEditField.Limits = [1 Inf];
            app.firstFrameEditField.RoundFractionalValues = 'on';
            app.firstFrameEditField.Editable = 'off';
            app.firstFrameEditField.Value = 1;
            app.Label = uilabel(app.SegmentationTab);
            app.Label.Text = '-';
            app.LastFrameEditField = uieditfield(app.SegmentationTab, 'numeric');
            app.LastFrameEditField.Limits = [1 Inf];
            app.LastFrameEditField.RoundFractionalValues = 'on';
            app.LastFrameEditField.Value = Inf;
            app.numberofshadowsDropDownLabel = uilabel(app.SegmentationTab);
            app.numberofshadowsDropDownLabel.Text = 'number of shadows';
            app.numberofshadowsDropDown = uidropdown(app.SegmentationTab);
            app.numberofshadowsDropDown.Items = {'0', '1', '2'};
            app.numberofshadowsDropDown.ValueChangedFcn = createCallbackFcn(app, @numberofshadowsDropDownValueChanged, true);
            app.numberofshadowsDropDown.Value = '2';
            app.nointegralzCheckBox = uicheckbox(app.SegmentationTab);
            app.nointegralzCheckBox.Text = 'no integral z';
            app.integrationscaleEditFieldLabel = uilabel(app.SegmentationTab);
            app.integrationscaleEditFieldLabel.Text = 'integration scale';
            app.integrationscaleEditField = uieditfield(app.SegmentationTab, 'numeric');
            app.integrationscaleEditField.Value = 1;
            app.Thresholdch1EditFieldLabel = uilabel(app.SegmentationTab);
            app.Thresholdch1EditFieldLabel.Text = 'Threshold (ch1)';
            app.Thresholdch1EditField = uieditfield(app.SegmentationTab, 'numeric');
            app.Thresholdch2EditFieldLabel = uilabel(app.SegmentationTab);
            app.Thresholdch2EditFieldLabel.Text = 'Threshold (ch2)';
            app.Thresholdch2EditField = uieditfield(app.SegmentationTab, 'numeric');
            app.ifapplicableLabel = uilabel(app.SegmentationTab);
            app.ifapplicableLabel.Text = '(if applicable)';
            app.segAxes = uiaxes(app.SegmentationTab);
            app.segAxes.XTick = [];
            app.segAxes.YTick = [];
            app.segAxes.ZTick = [];
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            app.runTrackmRNADynamics = uibutton(app.TrackingTab, 'push');
            app.runTrackmRNADynamics.ButtonPushedFcn = createCallbackFcn(app, @runTrackmRNADynamicsButtonPushed, true);
            app.runTrackmRNADynamics.Text = 'Run tracking';
            app.runCheckParticleTracking = uibutton(app.TrackingTab, 'push');
            app.runCheckParticleTracking.ButtonPushedFcn = createCallbackFcn(app, @runCheckParticleTrackingButtonPushed, true);
            app.runCheckParticleTracking.Text = 'Check tracking';
            app.integrationscaleEditField_2Label = uilabel(app.TrackingTab);
            app.integrationscaleEditField_2Label.Text = 'integration scale';
            app.integrationscaleEditField_2 = uieditfield(app.TrackingTab, 'numeric');
            app.integrationscaleEditField_2.Value = 1;
            app.trackAxes2 = uiaxes(app.TrackingTab);
            app.trackAxes2.XTick = [];
            app.trackAxes2.YTick = [];
            app.trackAxes2.ZTick = [];
            app.trackAxes1 = uiaxes(app.TrackingTab);
            app.trackAxes1.XTick = [];
            app.trackAxes1.YTick = [];
            app.trackAxes1.ZTick = [];
            app.AxisregistrationTab = uitab(app.TabGroup);
            app.AxisregistrationTab.Title = 'Axis registration';
            app.runFindAPAxis = uibutton(app.AxisregistrationTab, 'push');
            app.runFindAPAxis.ButtonPushedFcn = createCallbackFcn(app, @runFindAPAxisButtonPushed, true);
            app.runFindAPAxis.Text = 'Find AP Axis';
            app.runAddParticlePosition = uibutton(app.AxisregistrationTab, 'push');
            app.runAddParticlePosition.ButtonPushedFcn = createCallbackFcn(app, @runAddParticlePositionButtonPushed, true);
            app.runAddParticlePosition.Text = 'Add particle positions';
            app.CorrectAxisCheckBox = uicheckbox(app.AxisregistrationTab);
            app.CorrectAxisCheckBox.Text = 'Correct Axis';
            app.ManualAlignmentCheckBox = uicheckbox(app.AxisregistrationTab);
            app.ManualAlignmentCheckBox.Text = 'Manual Alignment';
            app.runCheckDivisionTimes = uibutton(app.AxisregistrationTab, 'push');
            app.runCheckDivisionTimes.ButtonPushedFcn = createCallbackFcn(app, @runCheckDivisionTimesButtonPushed, true);
            app.runCheckDivisionTimes.Text = 'Check Divisions';
            app.fullEmbryoAxis = uiaxes(app.AxisregistrationTab);
            app.fullEmbryoAxis.XTick = [];
            app.fullEmbryoAxis.YTick = [];
            app.fullEmbryoAxis.ZTick = [];
            app.PostanalysisTab = uitab(app.TabGroup);
            app.PostanalysisTab.Title = 'Post analysis';
            app.runCompileParticles = uibutton(app.PostanalysisTab, 'push');
            app.runCompileParticles.ButtonPushedFcn = createCallbackFcn(app, @runCompileParticlesButtonPushed, true);
            app.runCompileParticles.Text = 'Compile Particles';
            app.skipallCheckBox = uicheckbox(app.PostanalysisTab);
            app.skipallCheckBox.Text = 'skip all';
            app.approveallCheckBox = uicheckbox(app.PostanalysisTab);
            app.approveallCheckBox.Text = 'approve all';
            app.approveallCheckBox.Value = true;
            app.minimumparticlesperAPbinEditFieldLabel = uilabel(app.PostanalysisTab);
            app.minimumparticlesperAPbinEditFieldLabel.Text = 'minimum particles per AP bin';
            app.minimumparticlesperAPbinEditField = uieditfield(app.PostanalysisTab, 'numeric');
            app.minimumparticlesperAPbinEditField.Value = 4;
            app.minimumframesinaparticletraceEditFieldLabel = uilabel(app.PostanalysisTab);
            app.minimumframesinaparticletraceEditFieldLabel.Text = 'minimum frames in a particle trace';
            app.minimumframesinaparticletraceEditField = uieditfield(app.PostanalysisTab, 'numeric');
            app.minimumframesinaparticletraceEditField.Value = 1;
            app.integrationareaDropDownLabel = uilabel(app.PostanalysisTab);
            app.integrationareaDropDownLabel.Text = 'integration area';
            app.integrationareaDropDown = uidropdown(app.PostanalysisTab);
            app.integrationareaDropDown.Items = {'109', '437'};
            app.integrationareaDropDown.Value = '109';
            app.ROICheckBox = uicheckbox(app.PostanalysisTab);
            app.ROICheckBox.Text = 'ROI';
            app.ROI1EditFieldLabel = uilabel(app.PostanalysisTab);
            app.ROI1EditFieldLabel.Text = 'ROI 1';
            app.ROI1EditField = uieditfield(app.PostanalysisTab, 'numeric');
            app.ROI2EditFieldLabel = uilabel(app.PostanalysisTab);
            app.ROI2EditFieldLabel.Text = 'ROI 2';
            app.ROI2EditField = uieditfield(app.PostanalysisTab, 'numeric');
            app.nohistonechannelCheckBox = uicheckbox(app.PostanalysisTab);
            app.nohistonechannelCheckBox.Text = 'no histone channel';
            app.NuclearsegmentationandtrackingTab = uitab(app.TabGroup);
            app.NuclearsegmentationandtrackingTab.Title = 'Nuclear segmentation and tracking';
            app.runTrackNuclei = uibutton(app.NuclearsegmentationandtrackingTab, 'push');
            app.runTrackNuclei.ButtonPushedFcn = createCallbackFcn(app, @runTrackNucleiButtonPushed, true);
            app.runTrackNuclei.Text = 'Track Nuclei ';
            app.Tr2DCheckBox = uicheckbox(app.NuclearsegmentationandtrackingTab);
            app.Tr2DCheckBox.Text = 'Tr2D';
            app.runCheckNucleiSegmentation = uibutton(app.NuclearsegmentationandtrackingTab, 'push');
            app.runCheckNucleiSegmentation.ButtonPushedFcn = createCallbackFcn(app, @runCheckNucleiSegmentationButtonPushed, true);
            app.runCheckNucleiSegmentation.Text = 'Check Nuclei Segmentation';
            app.Panel = uipanel(app.LivemRNAAnalysisUIFigure);
            app.PrefixEditField_4Label = uilabel(app.Panel);
            app.PrefixEditField_4Label.Text = 'Prefix';
            app.PrefixEditField = uieditfield(app.Panel, 'text');
            app.PrefixEditField.ValueChangedFcn = createCallbackFcn(app, @prefixLoaded, true);
            app.MovieexportedLampLabel = uilabel(app.Panel);
            app.MovieexportedLampLabel.Text = 'Movie exported';
            app.MovieexportedLamp = uilamp(app.Panel);
            app.MoviefilteredLampLabel = uilabel(app.Panel);
            app.MoviefilteredLampLabel.Text = 'Movie filtered';
            app.MoviefilteredLamp = uilamp(app.Panel);
            app.ParticlessegmentedLampLabel = uilabel(app.Panel);
            app.ParticlessegmentedLampLabel.Text = 'Particles segmented';
            app.ParticlessegmentedLamp = uilamp(app.Panel);
            app.ParticlestrackedLampLabel = uilabel(app.Panel);
            app.ParticlestrackedLampLabel.Text = 'Particles  tracked';
            app.ParticlestrackedLamp = uilamp(app.Panel);
            app.CheckFrameInfoButton = uibutton(app.Panel, 'push');
            app.CheckFrameInfoButton.Enable = 'off';
            app.CheckFrameInfoButton.Text = 'Check FrameInfo';
            app.ExperimenttypeTextAreaLabel = uilabel(app.Panel);
            app.ExperimenttypeTextAreaLabel.Text = 'Experiment type';
            app.Experimenttype = uitextarea(app.Panel);
            app.Experimenttype.Editable = 'off';
            app.RefreshButton = uibutton(app.Panel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @prefixLoaded, true);
            app.RefreshButton.Text = 'Refresh';
            app.ChecklogfileButton = uibutton(app.Panel, 'push');
            app.ChecklogfileButton.Enable = 'off';
            app.ChecklogfileButton.Text = 'Check log file';
            app.Channel1Label = uilabel(app.Panel);
            app.Channel1Label.Text = 'Channel 1';
            app.ch1Label = uitextarea(app.Panel);
            app.ch1Label.Editable = 'off';
            app.Channel3Label = uilabel(app.Panel);
            app.Channel3Label.Text = 'Channel 3';
            app.ch3Label = uitextarea(app.Panel);
            app.ch3Label.Editable = 'off';
            app.Channel2Label = uilabel(app.Panel);
            app.Channel2Label.Text = 'Channel 2';
            app.ch2Label = uitextarea(app.Panel);
            app.ch2Label.Editable = 'off';
            app.APaxisregisteredLampLabel = uilabel(app.Panel);
            app.APaxisregisteredLampLabel.Text = 'AP axis registered';
            app.APaxisregisteredLamp = uilamp(app.Panel);
            app.ParticlesregisteredtoaxisLampLabel = uilabel(app.Panel);
            app.ParticlesregisteredtoaxisLampLabel.Text = 'Particles registered to axis';
            app.ParticlesregisteredtoaxisLamp = uilamp(app.Panel);
            app.NucleitrackedLampLabel = uilabel(app.Panel);
            app.NucleitrackedLampLabel.Text = 'Nuclei tracked';
            app.NucleitrackedLamp = uilamp(app.Panel);
            app.MitosescheckedLampLabel = uilabel(app.Panel);
            app.MitosescheckedLampLabel.Text = 'Mitoses checked';
            app.MitosescheckedLamp = uilamp(app.Panel);
            app.ParticlescompiledLabel = uilabel(app.Panel);
            app.ParticlescompiledLabel.Text = 'Particles compiled';
            app.ParticlescompiledLamp = uilamp(app.Panel);
            app.PopupPanel = uipanel(app.LivemRNAAnalysisUIFigure);
            app.PopupPanel.Title = 'Pop up';
            app.PopupPanel.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = livemRNAApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LivemRNAAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LivemRNAAnalysisUIFigure)
        end
    end
end