classdef segmentationAppBeta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        zSliceSliderLabel  matlab.ui.control.Label
        zSliceSlider       matlab.ui.control.Slider
        LoadProjectButton  matlab.ui.control.Button
        FrameSliderLabel   matlab.ui.control.Label
        FrameSlider        matlab.ui.control.Slider
        ExitButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        Prefix = ''; % Description
        xq = [];
        yq = [];
        xDim = [];
        yDim = [];
        image_labels = [];
        DropboxFolder = '';
        im = [];
    end
    
    methods (Access = private)
        
        function update_plot(app,zSlice,frame)
            [SourcePath,ProcPath,DropboxFolder,MS2CodePath, PreProcPath,...
                Folder, ~, ExperimentType,Channel1,Channel2,OutputFolder, Channel3] = readMovieDatabase(app.Prefix);
            tiffPath = [PreProcPath '/' app.Prefix '/'];
            if strcmpi(ExperimentType,'2spot2color')
                error('2 spot 2 color experiment not currently supported')
            end
            mcpChannel = find(contains([Channel1,Channel2,Channel3],'MCP'));            
            fileName = [app.Prefix '_' sprintf('%03d',frame) '_z' sprintf('%02d',zSlice)  '_ch' sprintf('%02d',mcpChannel) '.tif'];
            % update global variable (here and only here)
            app.im = imadjust(mat2gray(imread([tiffPath fileName])));
            % call image plotting function            ;            
            plot_image(app,zSlice,frame)                      
        end
    
        % open seperate window to draw labels
        function freehandROI(app,im,zSlice,frame,fov_origin)
            [yD,xD] = size(im);
            RGB = zeros(yD,xD,3);
            RGB(:,:,:) = repmat(im,1,1,3)*.8;
            
            lbl_indices = app.image_labels{zSlice,frame};
            
            if ~isempty(lbl_indices)
                sliceSpot = zeros(yD,xD);
                sliceNotSpot = zeros(yD,xD);                        
                
                lbl_orig = lbl_indices(:,2);
                [yO,xO] = ind2sub([app.yDim,app.xDim],lbl_orig);
                newCoord(:,1) = yO - fov_origin(1) + 1;
                newCoord(:,2) = xO - fov_origin(2) + 1;
                new_ft = min(newCoord,[],2)>0&newCoord(:,1)<=yD&newCoord(:,2)<=xD;               
                newCoord = newCoord(new_ft,:);
%                 new_ft = true(1,size(lbl_orig,1));
                lbl_new = sub2ind(size(im),newCoord(:,1),newCoord(:,2));
                
                lbl_updated = lbl_indices(new_ft,:);
                lbl_updated(:,2) = lbl_new;                
                
                sliceSpot(lbl_updated(lbl_updated(:,1)==1,2)) = .2;
                sliceNotSpot(lbl_updated(lbl_updated(:,1)==0,2)) = .2;
                RGB(:,:,1) = RGB(:,:,1) + sliceSpot;
                RGB(:,:,2) = RGB(:,:,2) + sliceNotSpot;
            end
            
            temp_fig = figure;
            ax = gca;%axes(app.UIFigure);
            mag = 100 * max([app.xDim,app.yDim]) / max([yD,xD]);
            imshow(RGB,'parent',ax,'InitialMagnification',mag)
            hold on
            h = drawfreehand(ax,'FaceAlpha',.2,'LineWidth',1);                         
            % lazy code to deal with exit shenanagins
            try
                xy = h.Position;
            catch
                return
            end
            xy = xy + fliplr(fov_origin) - [1, 1];
            [in, on] = inpolygon(app.xq,app.yq,xy(:,2),xy(:,1));
            newIndices = vertcat(find(in),find(on));
            % if empty, return to plot funct
            if isempty(newIndices)
                warning('No points specified. User must create shape of size > 1 px')
                delete(h);            
                return
            end
            % check to see if existing region was selected
            if ~isempty(lbl_indices)
                currentIndices = lbl_indices(:,2);    
            else
                currentIndices = [];
            end           
            overlap = ismember(newIndices,currentIndices);
            if any(overlap) && ~all(overlap)
                warning('Specified redgion overlaps with existing label. Taking complement')
                newIndices = newIndices(~overlap);     
            end
            if all(overlap)
                warning('Removing selected label region')
                ref_slice = zeros(app.yDim,app.xDim);
                ref_slice(currentIndices) = 1;
                ref_lbl = bwlabel(ref_slice);
                rm_id = unique(ref_lbl(newIndices));                
                rm_indices = find(ref_lbl==rm_id); 
                lbl_indices = lbl_indices(~ismember(currentIndices,rm_indices),:);
            else               
                % determine region identity
                titleBarCaption = 'Enter Region Type';
                promptMessage = sprintf('');
                button = questdlg(promptMessage, titleBarCaption, 'Spot', 'NotSpot','Zoom','NotSpot');
                            
                if strcmpi(button,'Spot')                
                    lb_mat = [ones(size(newIndices)), newIndices];
                    lbl_indices = vertcat(lbl_indices,lb_mat);
                elseif strcmpi(button,'NotSpot')
                    lb_mat = [zeros(size(newIndices)), newIndices];
                    lbl_indices = vertcat(lbl_indices,lb_mat);
                elseif strcmpi(button,'Zoom')
                    warning('down the rabbit hole we go...')
                    [yC,xC] = ind2sub([app.yDim,app.xDim],newIndices);
                    yC = round(mean(yC));
                    xC = round(mean(xC));
                    yDNew = round(yD/3/2);
                    xDNew = round(xD/3/2);
                    
                    x_range_true = max(1,xC-xDNew):min(app.xDim,xC+xDNew);
                    y_range_true = max(1,yC-yDNew):min(app.yDim,yC+yDNew);
                    
                    new_origin = [min(y_range_true),min(x_range_true)];
                    imNew = app.im(y_range_true,x_range_true);
                    freehandROI(app,imNew,zSlice,frame,new_origin)
                end                
            end                   
            app.image_labels{zSlice,frame} = lbl_indices;
            out_data = app.image_labels;
            save([app.DropboxFolder '/' app.Prefix '/spot_segmentation_labels.mat'],'out_data');
            
            delete(h);
            
            % Call plot_image function and so on...
            plot_image(app,zSlice,frame)
%             close temp_fig
        end
        
        % update UI plot to reflect current labels 
        function plot_image(app,zSlice,frame)
            RGB = zeros(app.yDim,app.xDim,3);
            RGB(:,:,:) = repmat(app.im,1,1,3)*.8;
            % get current labels
            lbl_indices = app.image_labels{zSlice,frame};
            if ~isempty(lbl_indices)
                sliceSpot = zeros(app.yDim,app.xDim);
                sliceNotSpot = zeros(app.yDim,app.xDim);                        
                
                sliceSpot(lbl_indices(lbl_indices(:,1)==1,2)) = .2;
                sliceNotSpot(lbl_indices(lbl_indices(:,1)==0,2)) = .2;
                RGB(:,:,1) = RGB(:,:,1) + sliceSpot;
                RGB(:,:,2) = RGB(:,:,2) + sliceNotSpot;
            end          
            imshow(RGB,'parent',app.UIAxes)             
            % call ROI labelling function
            freehandROI(app,app.im,zSlice,frame,[1,1]);                                   
        end
        
        % check for existence of spot label set
        function out_data = get_label_set(app,nFrames,nSlices)
            fPath = [app.DropboxFolder '/' app.Prefix '/spot_segmentation_labels.mat'];
            if isfile(fPath)
                 load(fPath,'out_data')
            else
                 out_data = cell(nSlices,nFrames);
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.Prefix = '';
        end

        % Value changed function: zSliceSlider
        function zSliceSliderValueChanged(app, event)
            zSlice = round(app.zSliceSlider.Value);
            frame = round(app.FrameSlider.Value);
            update_plot(app,zSlice,frame)
        end

        % Button pushed function: LoadProjectButton
        function LoadProjectButtonPushed(app, event)
            % Establish project Prefix
            path = uigetdir;
            slashes = strfind(path,'\');
            Prefix = path(slashes(end-1)+1:end);
            Prefix = strrep(Prefix,'\','-');
            app.Prefix = Prefix;
            
            % get list of available time points and z stacks
            [SourcePath,ProcPath,app.DropboxFolder,MS2CodePath, PreProcPath,...
                Folder, Prefix, ExperimentType,Channel1,Channel2,OutputFolder, Channel3] = readMovieDatabase(app.Prefix);
           
            % set limits on z size and number of frames
            load([app.DropboxFolder, filesep, app.Prefix, filesep, 'FrameInfo.mat']);
            zMax = FrameInfo(1).NumberSlices + 2;
            app.zSliceSlider.Limits = [1, zMax];
            pixelSize = FrameInfo(1).PixelSize * 1000; %nm
            frameMax = length(FrameInfo);
            app.FrameSlider.Limits = [1, frameMax];
            
            % get frame dims
            app.yDim = FrameInfo(1).LinesPerFrame;
            app.xDim = FrameInfo(1).PixelsPerLine;
            [y, x] = meshgrid(1:app.yDim,1:app.xDim);
            app.xq = x(:);
            app.yq = y(:);
            
            % check for existence of data structure w/ image labels
            app.image_labels = get_label_set(app,frameMax,zMax);
            
            % load initial image
            zSlice = round(app.zSliceSlider.Value);
            frame = round(app.FrameSlider.Value); 
            update_plot(app,zSlice,frame)
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            zSlice = round(app.zSliceSlider.Value);
            frame = round(app.FrameSlider.Value);         
            update_plot(app,zSlice,frame)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            
            delete(app)
            close all force            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.zSliceSliderLabel = uilabel(app.UIFigure);
            app.zSliceSliderLabel.Text = 'z Slice';
            app.zSliceSlider = uislider(app.UIFigure);
            app.zSliceSlider.Limits = [0 21];
            app.zSliceSlider.ValueChangedFcn = createCallbackFcn(app, @zSliceSliderValueChanged, true);
            app.LoadProjectButton = uibutton(app.UIFigure, 'push');
            app.LoadProjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProjectButtonPushed, true);
            app.LoadProjectButton.Text = 'Load Project';
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = segmentationAppBeta

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end